classdef enginetradesviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ShowthermalstresslimitsButton  matlab.ui.control.StateButton
        Sliderk_wall                   matlab.ui.control.Slider
        k_wallWmKLabel                 matlab.ui.control.Label
        num_channelsSliderLabel        matlab.ui.control.Label
        Sliderwallt                    matlab.ui.control.Slider
        wallthicknessmmSliderLabel     matlab.ui.control.Label
        Slidermdot                     matlab.ui.control.Slider
        dtmmLabel                      matlab.ui.control.Label
        SliderT_coolant                matlab.ui.control.Slider
        T_coolantdegCSlider_2Label     matlab.ui.control.Label
        Sliderexpansion_ratio          matlab.ui.control.Slider
        expansionratioSliderLabel      matlab.ui.control.Label
        Slidernum_channels             matlab.ui.control.Slider
        SliderOF                       matlab.ui.control.Slider
        OFSliderLabel                  matlab.ui.control.Label
        Sliderd_channel                matlab.ui.control.Slider
        d_channelmmSliderLabel         matlab.ui.control.Label
        Sliderpc                       matlab.ui.control.Slider
        pcpsiSliderLabel               matlab.ui.control.Label
        yaxisDropDown                  matlab.ui.control.DropDown
        yaxisDropDownLabel             matlab.ui.control.Label
        UIAxesk_wall                   matlab.ui.control.UIAxes
        UIAxeswallt                    matlab.ui.control.UIAxes
        UIAxesmdot                     matlab.ui.control.UIAxes
        UIAxesT_coolant                matlab.ui.control.UIAxes
        UIAxesexpansion_ratio          matlab.ui.control.UIAxes
        UIAxesnum_channels             matlab.ui.control.UIAxes
        UIAxesOF                       matlab.ui.control.UIAxes
        UIAxesd_channel                matlab.ui.control.UIAxes
        UIAxespc                       matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function fRefreshPlots(app, yaxisname)
            % For debugging
            disp("Refreshing plots");
            
            % Assume enginetradescalculator.m is in same folder
            load("engines.mat");

            tol_low = 1E-12;

            % for showing extra thermal stress data
            boolextrathermals = app.ShowthermalstresslimitsButton.Value;

            for i_var = 1:length(list_var_names)
                var_name = list_var_names(i_var);
                y_vals = eval(yaxisname);

                y_vals_indices = ones(1, length(list_var_names));
                y_vals_indices = num2cell(y_vals_indices);
                for i_i = 1:length(y_vals_indices)
                    a = 1;
                    c = 0;
                    % switch i_i
                    %     case 1
                    %         a = a ./ 0.000145038;
                    %     case 4
                    %         a = a .* 0.0254;
                    %     case 5
                    %         a = a ./ 100;
                    %     case 6
                    %         a = a ./ 1000;
                    %     case 8
                    %         c = 273.15;
                    %     case 9
                    %         a = a ./ 1000;
                    % end
                    y_vals_indices{i_i} = find(abs(ranges{i_i} - (a .* app.("Slider" + list_var_names(i_i)).Value + c)) <= tol_low, 1);
                end

                y_vals_indices{i_var} = 1:length(ranges{i_var});

                y_vals = squeeze(y_vals(y_vals_indices{:}));
                % Fixes weird MATLAB quirk where when squeezing everything
                %   but the second dimension of an ndarray, the resultant
                %   is a row vector. But, I want col vectors for the
                %   following switch case
                if i_var == 2
                    y_vals = y_vals(:);
                end
                switch yaxisname
                    case "thermstress"
                        if boolextrathermals
                            y_vals(:, 2) = yieldstress_max;
                            y_vals(:, 3) = yieldstress_alloy;
                        end
                    case "Twg"
                        y_vals(:, 2) = T_w_max;
                        y_vals(:, 3) = T_AlSi10Mg_melt;
                    case "P_coolant_min"
                        % if not "varying pc" graph
                        if i_var ~= 1
                            y_vals(:, 2) = app.("Slider" + list_var_names(i_i)).Value;
                        end
                end
                try
                    plot(eval("app.UIAxes" + var_name), ranges{i_var}, y_vals);
                    legend(eval("app.UIAxes" + var_name), "off");
                catch
                    error("Couldn't plot, likely problem with y_vals");
                end
            end
            switch yaxisname
                case "thermstress"
                    if boolextrathermals
                        for i_var = 1:length(list_var_names)
                            var_name = list_var_names(i_var);
                            legend(eval("app.UIAxes" + var_name), "thermal stress", "max allowable stress", "alloy yield stress");
                        end
                    % else

                    end
                case "Twg"
                    for i_var = 1:length(list_var_names)
                        var_name = list_var_names(i_var);
                        legend(eval("app.UIAxes" + var_name), "hot-side hot wall temp", "max allowable temp", "alloy melt temp");
                    end
                case "P_coolant_min"
                    for i_var = 1:length(list_var_names)
                        % if not "varying pc" graph, show how min. required
                        % coolant press compares to specified chamber press
                        if i_var ~= 1
                            var_name = list_var_names(i_var);
                            legend(eval("app.UIAxes" + var_name), "min allowable coolant press", "pc");
                        end
                    end
            end
            drawnow;
        end
        
        function snapSlider(app, slidername)
            val = app.(slidername).Value;
            mjrs = app.(slidername).MajorTicks;
            idx2 = find(mjrs > val, 1);
            if isempty(idx2)
                idx2 = length(mjrs);
            end
            idx1 = idx2 - 1;
            dif2 = abs(mjrs(idx2) - val);
            dif1 = abs(mjrs(idx1) - val);
            if dif2 <= dif1
                val = mjrs(idx2);
            else
                val = mjrs(idx1);
            end

            app.(slidername).Value = val;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            % Assume file is in same folder
            load("engines.mat");
            app.yaxisDropDown.Items = ["thermstress (psi)", "Twg (deg C)", "T_coolant_f (deg C)", "P_coolant_min (psi)", "Isp (s)", "thrust (lbf)", "prop_cost ($)", "dt (mm)", "de (mm)", "cstar (ft/s)", "eta_cstar (%)"];

            for i_var = 1:length(list_var_names)
                try
                    varname = list_var_names(i_var);
                    axesname = "UIAxes" + varname;
                    axesobj = app.(axesname);
                    slidername = "Slider" + varname;
                    sliderobj = app.(slidername);
                    sliderobj.Limits = [ranges{i_var}(1), ranges{i_var}(end)];
                    sliderobj.MajorTicks = ranges{i_var};
                catch
                    if exist(varname, "var")
                        error("Couldn't initiate app for %s data\nrange: %f", varname, ranges{i_var});
                    else
                        error("Couldn't initiate varname %d in app startup", i_var);
                    end
                end
            end

            % Initial plots
            yaxisname = split(string(app.yaxisDropDown.Items(1)), " ");
            yaxisname = yaxisname(1);
            fRefreshPlots(app, yaxisname)
        end

        % Value changed function: yaxisDropDown
        function yaxisDropDownValueChanged(app, event)
            yaxisname = split(string(app.yaxisDropDown.Value), " ");
            yaxisname = yaxisname(1);
            
            % Refresh Plots
            fRefreshPlots(app, yaxisname);
        end

        % Value changed function: Sliderpc
        function SliderpcValueChanged(app, event)
            snapSlider(app, "Sliderpc");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: SliderOF
        function SliderOFValueChanged(app, event)
            snapSlider(app, "SliderOF");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderexpansion_ratio
        function Sliderexpansion_ratioValueChanged(app, event)
            snapSlider(app, "Sliderexpansion_ratio");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Slidermdot
        function SlidermdotValueChanged(app, event)
            snapSlider(app, "Sliderdt");
            yaxisDropDownValueChanged(app, event);
        end

        % Callback function: not associated with a component
        function Slidereta_cstarValueChanged(app, event)
            snapSlider(app, "Slidereta_cstar");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderd_channel
        function Sliderd_channelValueChanged(app, event)
            snapSlider(app, "Sliderd_channel");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Slidernum_channels
        function Slidernum_channelsValueChanged(app, event)
            snapSlider(app, "Slidernum_channels");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: SliderT_coolant
        function SliderT_coolantValueChanged(app, event)
            snapSlider(app, "SliderT_coolant");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderwallt
        function SliderwalltValueChanged(app, event)
            snapSlider(app, "Sliderwallt");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderk_wall
        function Sliderk_wallValueChanged(app, event)
            snapSlider(app, "Sliderk_wall");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: ShowthermalstresslimitsButton
        function ShowthermalstresslimitsButtonValueChanged(app, event)
            value = app.ShowthermalstresslimitsButton.Value;
            yaxisDropDownValueChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxespc = uiaxes(app.UIFigure);
            title(app.UIAxespc, 'varying pc')
            xlabel(app.UIAxespc, 'X')
            ylabel(app.UIAxespc, 'Y')
            zlabel(app.UIAxespc, 'Z')
            app.UIAxesd_channel = uiaxes(app.UIFigure);
            title(app.UIAxesd_channel, 'varying d channels')
            xlabel(app.UIAxesd_channel, 'X')
            ylabel(app.UIAxesd_channel, 'Y')
            zlabel(app.UIAxesd_channel, 'Z')
            app.UIAxesOF = uiaxes(app.UIFigure);
            title(app.UIAxesOF, 'varying OF')
            xlabel(app.UIAxesOF, 'X')
            ylabel(app.UIAxesOF, 'Y')
            zlabel(app.UIAxesOF, 'Z')
            app.UIAxesnum_channels = uiaxes(app.UIFigure);
            title(app.UIAxesnum_channels, 'varying # of channels')
            xlabel(app.UIAxesnum_channels, 'X')
            ylabel(app.UIAxesnum_channels, 'Y')
            zlabel(app.UIAxesnum_channels, 'Z')
            app.UIAxesexpansion_ratio = uiaxes(app.UIFigure);
            title(app.UIAxesexpansion_ratio, 'varying expansion ratio')
            xlabel(app.UIAxesexpansion_ratio, 'X')
            ylabel(app.UIAxesexpansion_ratio, 'Y')
            zlabel(app.UIAxesexpansion_ratio, 'Z')
            app.UIAxesT_coolant = uiaxes(app.UIFigure);
            title(app.UIAxesT_coolant, 'varying T coolant')
            xlabel(app.UIAxesT_coolant, 'X')
            ylabel(app.UIAxesT_coolant, 'Y')
            zlabel(app.UIAxesT_coolant, 'Z')
            app.UIAxesmdot = uiaxes(app.UIFigure);
            title(app.UIAxesmdot, 'varying mdot')
            xlabel(app.UIAxesmdot, 'X')
            ylabel(app.UIAxesmdot, 'Y')
            zlabel(app.UIAxesmdot, 'Z')
            app.UIAxeswallt = uiaxes(app.UIFigure);
            title(app.UIAxeswallt, 'varying wall thickness')
            xlabel(app.UIAxeswallt, 'X')
            ylabel(app.UIAxeswallt, 'Y')
            zlabel(app.UIAxeswallt, 'Z')
            app.UIAxesk_wall = uiaxes(app.UIFigure);
            title(app.UIAxesk_wall, 'varying k wall')
            xlabel(app.UIAxesk_wall, 'X')
            ylabel(app.UIAxesk_wall, 'Y')
            zlabel(app.UIAxesk_wall, 'Z')
            app.yaxisDropDownLabel = uilabel(app.UIFigure);
            app.yaxisDropDownLabel.Text = 'y-axis';
            app.yaxisDropDown = uidropdown(app.UIFigure);
            app.yaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @yaxisDropDownValueChanged, true);
            app.pcpsiSliderLabel = uilabel(app.UIFigure);
            app.pcpsiSliderLabel.Text = {'pc'; '(psi)'};
            app.Sliderpc = uislider(app.UIFigure);
            app.Sliderpc.ValueChangedFcn = createCallbackFcn(app, @SliderpcValueChanged, true);
            app.d_channelmmSliderLabel = uilabel(app.UIFigure);
            app.d_channelmmSliderLabel.Text = {'d_channel'; '(mm)'};
            app.Sliderd_channel = uislider(app.UIFigure);
            app.Sliderd_channel.ValueChangedFcn = createCallbackFcn(app, @Sliderd_channelValueChanged, true);
            app.OFSliderLabel = uilabel(app.UIFigure);
            app.OFSliderLabel.Text = 'OF';
            app.SliderOF = uislider(app.UIFigure);
            app.SliderOF.ValueChangedFcn = createCallbackFcn(app, @SliderOFValueChanged, true);
            app.Slidernum_channels = uislider(app.UIFigure);
            app.Slidernum_channels.ValueChangedFcn = createCallbackFcn(app, @Slidernum_channelsValueChanged, true);
            app.expansionratioSliderLabel = uilabel(app.UIFigure);
            app.expansionratioSliderLabel.Text = 'expansion ratio';
            app.Sliderexpansion_ratio = uislider(app.UIFigure);
            app.Sliderexpansion_ratio.ValueChangedFcn = createCallbackFcn(app, @Sliderexpansion_ratioValueChanged, true);
            app.T_coolantdegCSlider_2Label = uilabel(app.UIFigure);
            app.T_coolantdegCSlider_2Label.Text = {'T_coolant'; '(deg C)'};
            app.SliderT_coolant = uislider(app.UIFigure);
            app.SliderT_coolant.ValueChangedFcn = createCallbackFcn(app, @SliderT_coolantValueChanged, true);
            app.dtmmLabel = uilabel(app.UIFigure);
            app.dtmmLabel.Text = {'dt'; '(mm)'};
            app.Slidermdot = uislider(app.UIFigure);
            app.Slidermdot.ValueChangedFcn = createCallbackFcn(app, @SlidermdotValueChanged, true);
            app.wallthicknessmmSliderLabel = uilabel(app.UIFigure);
            app.wallthicknessmmSliderLabel.Text = {'wall thickness'; '(mm)'};
            app.Sliderwallt = uislider(app.UIFigure);
            app.Sliderwallt.ValueChangedFcn = createCallbackFcn(app, @SliderwalltValueChanged, true);
            app.num_channelsSliderLabel = uilabel(app.UIFigure);
            app.num_channelsSliderLabel.Text = 'num_channels';
            app.k_wallWmKLabel = uilabel(app.UIFigure);
            app.k_wallWmKLabel.Text = 'k_wall (W/m-K)';
            app.Sliderk_wall = uislider(app.UIFigure);
            app.Sliderk_wall.ValueChangedFcn = createCallbackFcn(app, @Sliderk_wallValueChanged, true);
            app.ShowthermalstresslimitsButton = uibutton(app.UIFigure, 'state');
            app.ShowthermalstresslimitsButton.ValueChangedFcn = createCallbackFcn(app, @ShowthermalstresslimitsButtonValueChanged, true);
            app.ShowthermalstresslimitsButton.Text = 'Show thermal stress limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enginetradesviewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end